classdef App1_2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        pic1                    matlab.ui.control.UIAxes
        LabelListBox            matlab.ui.control.Label
        ISI                     matlab.ui.control.ListBox
        LabelListBox2           matlab.ui.control.Label
        IQ                      matlab.ui.control.ListBox
        LabelNumericEditField   matlab.ui.control.Label
        nISI                    matlab.ui.control.NumericEditField
        LabelNumericEditField2  matlab.ui.control.Label
        nIQ                     matlab.ui.control.NumericEditField
        go                      matlab.ui.control.Button
        LabelTextArea           matlab.ui.control.Label
        Measurement             matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: go
        function GoButtonPushed(app, event)
            isi=app.ISI.Value;
            nisi=app.nISI.Value;
            iq=app.IQ.Value;
            niq=app.nIQ.Value;
            state= struct('ISI',isi,'nISI',nisi,'IQ',iq,'nIQ',niq);
            [original,compSig,estCoef,meas] = test_LMSandIQCompensator(state);
%%
            %picture 1
            clf(app.pic1);         
            thetaVector = -pi:0.1:pi;
            constellation = qammod(0:3, 4)/sqrt(2);       % symbols from 4-QAM constellation   
            
            plot(app.pic1, original,'k*','markers',1);
            hold(app.pic1,'on');

            plot(app.pic1, compSig,'b.','markers',1);

            plot(app.pic1, cos(thetaVector),sin(thetaVector),'r-','LineWidth',0.1);

            plot(app.pic1, real(constellation),imag(constellation),'ro',...
                     'MarkerSize',10,'LineWidth',1);
            hold(app.pic1,'off');
%%
% text area presents
            app.Measurement.Value={['ISI Impairments: ',state.ISI]; ['IQ Imbalance: ',state.IQ];...
                ['EVM_rx= ',meas.EVM_rx,'%'];['EVM_filted= ',meas.EVM_filted,'%']};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.pic1 = uiaxes(app.UIFigure);
            title(app.pic1, 'Recieved Data')
            xlabel(app.pic1, 'in−phase')
            ylabel(app.pic1, 'quadrature−phase')
            app.LabelListBox = uilabel(app.UIFigure);
            app.LabelListBox.Text = 'For ISI Impairments';
            app.ISI = uilistbox(app.UIFigure);
            app.ISI.Items = {'No Filter', 'LMS', 'RLS', 'Godard (blind)', 'Constant-Modulus Algorithm (blind)', 'Modified CMA (Blind)'};
            app.ISI.Value = 'No Filter';
            app.LabelListBox2 = uilabel(app.UIFigure);
            app.LabelListBox2.Text = 'For IQ Imbalance';
            app.IQ = uilistbox(app.UIFigure);
            app.IQ.Items = {'No Filter', 'Circularity-based approach'};
            app.IQ.Value = 'No Filter';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Number of Taps';
            app.nISI = uieditfield(app.UIFigure, 'numeric');
            app.nISI.Value = 5;
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Number of Taps';
            app.nIQ = uieditfield(app.UIFigure, 'numeric');
            app.nIQ.Value = 1;
            app.go = uibutton(app.UIFigure, 'push');
            app.go.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.go.Icon = '1200px-Van_Gogh_-_Trauernder_alter_Mann.jpeg';
            app.go.Text = 'Go !';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Measurement';
            app.Measurement = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1_2017

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end